classdef Copy_2_of_app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        RacquetTypePanel               matlab.ui.container.Panel
        TYPEDropDown                   matlab.ui.control.DropDown
        TYPEDropDownLabel              matlab.ui.control.Label
        ResetButton                    matlab.ui.control.Button
        DataRecordingPanel             matlab.ui.container.Panel
        LevelofFatigue2EditField       matlab.ui.control.EditField
        LevelofFatigue2EditFieldLabel  matlab.ui.control.Label
        LevelofFatigue1EditField       matlab.ui.control.EditField
        LevelofFatigue1EditFieldLabel  matlab.ui.control.Label
        Label                          matlab.ui.control.Label
        MeanFreq1Label                 matlab.ui.control.Label
        MeanFreq2Label                 matlab.ui.control.Label
        EMGPanel                       matlab.ui.container.Panel
        UIAxesEMG2                     matlab.ui.control.UIAxes
        UIAxesEMG1                     matlab.ui.control.UIAxes
        GYROSCOPEPanel                 matlab.ui.container.Panel
        UIAxes3D                       matlab.ui.control.UIAxes
        SPECTRUMPanel                  matlab.ui.container.Panel
        UIAxesFreq1                    matlab.ui.control.UIAxes
        UIAxesFreq2                    matlab.ui.control.UIAxes
        StartStopPanel                 matlab.ui.container.Panel
        StopButton                     matlab.ui.control.Button
        StartButton                    matlab.ui.control.Button
    end

    
    properties (Access = private)
        s               % สำหรับเก็บ TCP Connection
        fid             % สำหรับเก็บ File ID (CSV)
        isRunning       % สถานะการทำงานของ Loop (true/false)
        startTime       % จับเวลา
        
        % --- ตัวแปรสำหรับคำนวณ Fatigue ---
        baselineMNF1    
        baselineMNF2    
        isBaselineSet   
    
        % ตัวแปรเก็บเส้นกราฟ
        vecPlot         
        emg1AmpPlot     
        emg2AmpPlot     
        freq1Plot       
        freq2Plot
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
% 1. ตั้งค่ากราฟ 3D MPU6050
            app.vecPlot = plot3(app.UIAxes3D, [0, 0], [0, 0], [0, 0], '-o', 'Color', 'm', 'LineWidth', 3, 'MarkerSize', 8, 'MarkerFaceColor', 'c');
            app.UIAxes3D.Title.String = '3D MPU6050 Orientation';
            app.UIAxes3D.XLabel.String = 'Z (m/s^2)'; 
            app.UIAxes3D.YLabel.String = 'Y (m/s^2)'; 
            app.UIAxes3D.ZLabel.String = 'X (m/s^2)';
            axis(app.UIAxes3D, [-20 20 -20 20 -20 20]); 
            grid(app.UIAxes3D, 'on'); 
            view(app.UIAxes3D, 3);
            
            % 2. ตั้งค่ากราฟ EMG 1 และ 2 (Amplitude)
            app.emg1AmpPlot = animatedline(app.UIAxesEMG1, 'Color', 'r', 'LineWidth', 1.2, 'MaximumNumPoints', 1000);
            app.UIAxesEMG1.Title.String = '1. Amplitude EMG 1';
            app.UIAxesEMG1.XLabel.String = 'Time (s)'; app.UIAxesEMG1.YLabel.String = 'Amplitude';
            grid(app.UIAxesEMG1, 'on');
            app.emg2AmpPlot = animatedline(app.UIAxesEMG2, 'Color', 'b', 'LineWidth', 1.2, 'MaximumNumPoints', 1000);
            app.UIAxesEMG2.Title.String = '2. Amplitude EMG 2';
            app.UIAxesEMG2.XLabel.String = 'Time (s)'; app.UIAxesEMG2.YLabel.String = 'Amplitude';
            grid(app.UIAxesEMG2, 'on');
            
            % 3. ตั้งค่ากราฟ Spectrum 1 และ 2
            app.freq1Plot = stem(app.UIAxesFreq1, 0, 0, 'Color', [0.8500 0.3250 0.0980], 'LineWidth', 3, 'Marker', 'none');
            app.UIAxesFreq1.Title.String = '3. Spectrum EMG 1';
            app.UIAxesFreq1.XLabel.String = 'Frequency (Hz)'; 
            app.UIAxesFreq1.YLabel.String = 'Amplitude';
            app.UIAxesFreq1.XLim = [0 250]; 
            app.UIAxesFreq1.YLim = [0 5000]; 
            grid(app.UIAxesFreq1, 'on');
            
            app.freq2Plot = stem(app.UIAxesFreq2, 0, 0, 'Color', [0 0.4470 0.7410], 'LineWidth', 3, 'Marker', 'none');
            app.UIAxesFreq2.Title.String = '4. Spectrum EMG 2';
            app.UIAxesFreq2.XLabel.String = 'Frequency (Hz)'; 
            app.UIAxesFreq2.YLabel.String = 'Amplitude';
            app.UIAxesFreq2.XLim = [0 250]; 
            app.UIAxesFreq2.YLim = [0 5000]; 
            grid(app.UIAxesFreq2, 'on');
            
            app.isRunning = false;
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
           app.StartButton.Enable = 'off';
            app.StopButton.Enable = 'on';
            
            try
                ipAddress = '192.168.4.1';  
                port = 80;
                app.s = tcpclient(ipAddress, port, 'Timeout', 5); 
                
                % ดึงค่าสเปคไม้แบดมินตันจาก Dropdown
                racketSpec = app.TYPEDropDown.Value;
                
                % เปิดไฟล์บันทึก โดยเพิ่ม Header เก็บ Racket และ Fatigue Level
                fileName = sprintf('Blue_Badminton_Record_%s_1.csv', racketSpec);
                app.fid = fopen(fileName, 'w');
                fprintf(app.fid, 'Time(s),RacketSpec,EMG1_Amp,EMG2_Amp,AccX,AccY,AccZ,GyroX,GyroY,GyroZ,MNF1,MNF2,FatigueLevel1,FatigueLevel2\n');
                
                app.startTime = tic;
                app.isRunning = true;
                
                app.baselineMNF1 = 0;
                app.baselineMNF2 = 0;
                app.isBaselineSet = false;
                app.LevelofFatigue1EditField.Value = 'Wait...';
                app.LevelofFatigue2EditField.Value = 'Wait...';
                
                while app.isRunning
                    if app.s.NumBytesAvailable > 0
                        dataStr = readline(app.s);
                        dataNum = str2double(split(dataStr, ','));
                        
                        if length(dataNum) == 138 && ~any(isnan(dataNum))
                            currentTime = toc(app.startTime);
                            
                            amp1 = dataNum(1); amp2 = dataNum(2);
                            ax = dataNum(3); ay = dataNum(4); az = dataNum(5);
                            gx = dataNum(6); gy = dataNum(7); gz = dataNum(8);
                            
                            spec1 = dataNum(9:72);
                            spec2 = dataNum(73:136);
                            mnf1 = dataNum(137);
                            mnf2 = dataNum(138);
                            
                            % ตั้งค่าเริ่มต้นสำหรับตัวแปรบันทึกลง CSV
                            lvl1_log = 'Wait'; 
                            lvl2_log = 'Wait';
                            
                            if ~app.isBaselineSet && (mnf1 > 0 && mnf2 > 0)
                                app.baselineMNF1 = mnf1;
                                app.baselineMNF2 = mnf2;
                                app.isBaselineSet = true;
                            end
                            
                            if app.isBaselineSet
                                percent1 = (mnf1 / app.baselineMNF1) * 100;
                                percent2 = (mnf2 / app.baselineMNF2) * 100;
                                
                                % ประเมินระดับ มัดที่ 1
                                if percent1 >= 95
                                    lvl1_log = 'Level 1';
                                elseif percent1 >= 90
                                    lvl1_log = 'Level 2';
                                elseif percent1 >= 85
                                    lvl1_log = 'Level 3';
                                else
                                    lvl1_log = 'Level 4';
                                end
                                
                                % ประเมินระดับ มัดที่ 2
                                if percent2 >= 95
                                    lvl2_log = 'Level 1';
                                elseif percent2 >= 90
                                    lvl2_log = 'Level 2';
                                elseif percent2 >= 85
                                    lvl2_log = 'Level 3';
                                else
                                    lvl2_log = 'Level 4';
                                end
                                
                                app.LevelofFatigue1EditField.Value = sprintf('%s (%.1f%%)', lvl1_log, percent1);
                                app.LevelofFatigue2EditField.Value = sprintf('%s (%.1f%%)', lvl2_log, percent2);
                            end
                            
                            f = (1:64) * 3.90625;
                            app.MeanFreq1Label.Text = sprintf('MNF 1: %.2f Hz', mnf1);
                            app.MeanFreq2Label.Text = sprintf('MNF 2: %.2f Hz', mnf2);
                            
                            % บันทึก CSV แบบสมบูรณ์ (รวม Racket Spec และ Fatigue Level)
                            fprintf(app.fid, '%.4f,%s,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.4f,%.2f,%.2f,%s,%s\n', ...
                                currentTime, racketSpec, amp1, amp2, ax, ay, az, gx, gy, gz, mnf1, mnf2, lvl1_log, lvl2_log);
                            
                            set(app.vecPlot, 'XData', [0, az], 'YData', [0, ay], 'ZData', [0, ax]);
                            addpoints(app.emg1AmpPlot, currentTime, amp1);
                            addpoints(app.emg2AmpPlot, currentTime, amp2);
                            set(app.freq1Plot, 'XData', f, 'YData', spec1);
                            set(app.freq2Plot, 'XData', f, 'YData', spec2);
                            
                            drawnow limitrate;
                        end
                    else
                        pause(0.01);
                    end
                end
            catch ME
                disp(ME.message);
                if isvalid(app)
                    StopButtonPushed(app, event);
                end
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
app.isRunning = false;
            
            if ~isempty(app.fid)
                try
                    fclose(app.fid);
                catch
                end
            end
            if ~isempty(app.s)
                try
                    clear app.s;
                catch
                end
            end
            
            app.StartButton.Enable = 'on';
            app.StopButton.Enable = 'off';
            disp('หยุดการเก็บข้อมูล ไฟล์ถูกบันทึกสำเร็จ');
            end
        
        % Close request function
        function UIFigureCloseRequest(app, event)
            if app.isRunning
                StopButtonPushed(app, event);
            end
            delete(app);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            % 1. เคลียร์เส้นกราฟทั้งหมด
            clearpoints(app.emg1AmpPlot);
            clearpoints(app.emg2AmpPlot);
            
            % 2. รีเซ็ตกราฟ 3D และ Spectrum
            set(app.vecPlot, 'XData', [0, 0], 'YData', [0, 0], 'ZData', [0, 0]);
            set(app.freq1Plot, 'XData', 0, 'YData', 0);
            set(app.freq2Plot, 'XData', 0, 'YData', 0);
            
            % 3. รีเซ็ตค่าการคำนวณและ UI
            app.baselineMNF1 = 0;
            app.baselineMNF2 = 0;
            app.isBaselineSet = false;
            
            app.LevelofFatigue1EditField.Value = 'Wait...';
            app.LevelofFatigue2EditField.Value = 'Wait...';
            app.MeanFreq1Label.Text = 'MNF 1: 0.00 Hz';
            app.MeanFreq2Label.Text = 'MNF 2: 0.00 Hz';
            
            % รีเซ็ตเวลาจับ
            if app.isRunning
                app.startTime = tic;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StartStopPanel = uipanel(app.UIFigure);
            app.StartStopPanel.Title = 'Start \ Stop';
            app.StartButton = uibutton(app.StartStopPanel, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'START';
            app.StopButton = uibutton(app.StartStopPanel, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'STOP';
            app.SPECTRUMPanel = uipanel(app.UIFigure);
            app.SPECTRUMPanel.Title = 'SPECTRUM';
            app.UIAxesFreq2 = uiaxes(app.SPECTRUMPanel);
            title(app.UIAxesFreq2, 'Spectrum EMG-1')
            xlabel(app.UIAxesFreq2, 'Frequency')
            ylabel(app.UIAxesFreq2, 'Amplitude')
            zlabel(app.UIAxesFreq2, 'Z')
            app.UIAxesFreq1 = uiaxes(app.SPECTRUMPanel);
            title(app.UIAxesFreq1, 'Spectrum EMG-2')
            xlabel(app.UIAxesFreq1, 'Frequency')
            ylabel(app.UIAxesFreq1, 'Amplitude')
            zlabel(app.UIAxesFreq1, 'Z')
            app.GYROSCOPEPanel = uipanel(app.UIFigure);
            app.GYROSCOPEPanel.Title = 'GYROSCOPE';
            app.UIAxes3D = uiaxes(app.GYROSCOPEPanel);
            title(app.UIAxes3D, '3D')
            xlabel(app.UIAxes3D, 'X')
            ylabel(app.UIAxes3D, 'Y')
            zlabel(app.UIAxes3D, 'Z')
            app.EMGPanel = uipanel(app.UIFigure);
            app.EMGPanel.Title = 'EMG';
            app.UIAxesEMG1 = uiaxes(app.EMGPanel);
            title(app.UIAxesEMG1, 'EMG-1')
            xlabel(app.UIAxesEMG1, 'Time')
            ylabel(app.UIAxesEMG1, 'Amplitude')
            zlabel(app.UIAxesEMG1, 'Z')
            app.UIAxesEMG2 = uiaxes(app.EMGPanel);
            title(app.UIAxesEMG2, 'EMG-2')
            xlabel(app.UIAxesEMG2, 'Time')
            ylabel(app.UIAxesEMG2, 'Amplitude')
            zlabel(app.UIAxesEMG2, 'Z')
            app.DataRecordingPanel = uipanel(app.UIFigure);
            app.DataRecordingPanel.Title = 'Data Recording';
            app.MeanFreq2Label = uilabel(app.DataRecordingPanel);
            app.MeanFreq2Label.Text = 'MeanFreq1';
            app.MeanFreq1Label = uilabel(app.DataRecordingPanel);
            app.MeanFreq1Label.Text = 'MeanFreq2';
            app.Label = uilabel(app.DataRecordingPanel);
            app.Label.Text = '';
            app.LevelofFatigue1EditFieldLabel = uilabel(app.DataRecordingPanel);
            app.LevelofFatigue1EditFieldLabel.Text = 'Level of Fatigue1';
            app.LevelofFatigue1EditField = uieditfield(app.DataRecordingPanel, 'text');
            app.LevelofFatigue2EditFieldLabel = uilabel(app.DataRecordingPanel);
            app.LevelofFatigue2EditFieldLabel.Text = 'Level of Fatigue2';
            app.LevelofFatigue2EditField = uieditfield(app.DataRecordingPanel, 'text');
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.RacquetTypePanel = uipanel(app.UIFigure);
            app.RacquetTypePanel.Title = 'Racquet Type';
            app.TYPEDropDownLabel = uilabel(app.RacquetTypePanel);
            app.TYPEDropDownLabel.Text = 'TYPE';
            app.TYPEDropDown = uidropdown(app.RacquetTypePanel);
            app.TYPEDropDown.Items = {'Speed', 'Control', 'Power'};
            app.TYPEDropDown.Value = 'Speed';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Copy_2_of_app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end